classdef easy_calc < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Button_8            matlab.ui.control.Button
        Button_7            matlab.ui.control.Button
        Button_4            matlab.ui.control.Button
        Button_5            matlab.ui.control.Button
        Button_9            matlab.ui.control.Button
        Button_6            matlab.ui.control.Button
        Button_1            matlab.ui.control.Button
        Button_2            matlab.ui.control.Button
        Button_3            matlab.ui.control.Button
        SubstractButton     matlab.ui.control.Button
        DivideButton        matlab.ui.control.Button
        ClearButton         matlab.ui.control.Button
        MinusButton         matlab.ui.control.Button
        PlusButton          matlab.ui.control.Button
        LogButton           matlab.ui.control.Button
        Button_0            matlab.ui.control.Button
        DotButton           matlab.ui.control.Button
        EqualityButton      matlab.ui.control.Button
        DeleteButton        matlab.ui.control.Button
        LeftBracketButton   matlab.ui.control.Button
        RightBracketButton  matlab.ui.control.Button
        SquareButton        matlab.ui.control.Button
        Raising2Button      matlab.ui.control.Button
        CountingTextArea    matlab.ui.control.TextArea
        ResultTextArea      matlab.ui.control.TextArea
    end

    properties (Access = private)
        
        resultString = ''; % counting string
        finalResult = 0; % final result string
    end
    
    methods (Access = private)
        
        function results = resultStringAppending(app,value)
            % function which adding next values        %
            % to main counting string                  %
            % value = button value in number or string %
            %                                          %
            
            buttonValue = value; %assigning value of pushed button
        
            app.resultString = append(app.resultString,num2str(buttonValue)); %converting value from number to string
            app.CountingTextArea.Value = app.resultString; %updating counting text area
            
        end
        
        function results = resultStringAppendingOperators(app,value)
            % function which adding next values        %
            % to main counting string for operators    %
            % value = button value in number or string %
            %                                          %
            
            buttonValue = value; %assigning value of pushed button
            
            resultStringInChar = char(app.resultString);
            if app.resultString ~= ""
                %checking if was not typed double + and - and / and *
                if not(isequal(resultStringInChar(end),'+')) && not(isequal(resultStringInChar(end),'-')) && not(isequal(resultStringInChar(end),'*')) && not(isequal(resultStringInChar(end),'/')) && not(isequal(resultStringInChar(end),'')) && not(isequal(resultStringInChar(end),'^'))   
                    app.resultString = append(app.resultString,num2str(buttonValue)); %converting value from number to string
                    app.CountingTextArea.Value = app.resultString; %updating counting text area
                end
            end
        end
        
        function results = resultStringDeleting(app)
            % function which deleting last value       %
            % from main counting string                %
            %                                          %
            
            resultStringInChar = char(app.resultString);
            app.resultString = resultStringInChar(1:end-1);
            
            app.CountingTextArea.Value = app.resultString; %updating counting text area
        end
        
        function results = resultStringClearing(app)
            % function which deleting whole counting   %
            % string                                   %
            %                                          %
            
            app.resultString = '';
            app.finalResult = '';
            app.CountingTextArea.Value = app.resultString; %updating counting text area
            app.ResultTextArea.Value = app.finalResult; %updating result text area
        end
        
        function results = calculateFinalResult(app)
            
            try % errors handling with try, catch structure to protect from invalid expressions inserting
                app.finalResult = eval(char(app.resultString));
                if app.finalResult == 1/0
                    app.finalResult = 'Cannot divide by zero';
                end
            catch mexc
                %the exception you get for invalid expression
                app.finalResult = 'Invalid expression';
            end
            
            app.ResultTextArea.Value = string(app.finalResult);
            app.resultString = string(app.finalResult);
            app.CountingTextArea.Value = app.resultString;
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_0
        function Button_0Pushed(app, event)
               app.resultStringAppending(0)
        end

        % Button pushed function: Button_1
        function Button_1Pushed(app, event)
            app.resultStringAppending(1)
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.resultStringAppending(2)
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            app.resultStringAppending(3)
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            app.resultStringAppending(4)
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            app.resultStringAppending(5)
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            app.resultStringAppending(6)
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            app.resultStringAppending(7)
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            app.resultStringAppending(8)
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            app.resultStringAppending(9)
        end

        % Button pushed function: MinusButton
        function MinusButtonPushed(app, event)
            app.resultStringAppendingOperators('-')
        end

        % Button pushed function: PlusButton
        function PlusButtonPushed(app, event)
            app.resultStringAppendingOperators('+')
        end

        % Button pushed function: SubstractButton
        function SubstractButtonPushed(app, event)
            app.resultStringAppendingOperators('*')
        end

        % Button pushed function: DivideButton
        function DivideButtonPushed(app, event)
            app.resultStringAppendingOperators('/')
        end

        % Button pushed function: LeftBracketButton
        function LeftBracketButtonPushed(app, event)
            app.resultStringAppending('(')
        end

        % Button pushed function: RightBracketButton
        function RightBracketButtonPushed(app, event)
            app.resultStringAppending(')')
        end

        % Button pushed function: SquareButton
        function SquareButtonPushed(app, event)
            app.resultStringAppending('sqrt(')
        end

        % Button pushed function: Raising2Button
        function Raising2ButtonPushed(app, event)
            app.resultStringAppendingOperators('^') %appending like operators to protect from typing ^ as first character
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            app.resultStringDeleting()
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.resultStringClearing()
        end

        % Button pushed function: EqualityButton
        function EqualityButtonPushed(app, event)
            app.calculateFinalResult()
        end

        % Button pushed function: DotButton
        function DotButtonPushed(app, event)
            app.resultStringAppending('.')
        end

        % Button pushed function: LogButton
        function LogButtonPushed(app, event)
            app.resultStringAppending('log(')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button_8 = uibutton(app.UIFigure, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '8';
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '7';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '4';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '5';
            app.Button_9 = uibutton(app.UIFigure, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '9';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '6';
            app.Button_1 = uibutton(app.UIFigure, 'push');
            app.Button_1.ButtonPushedFcn = createCallbackFcn(app, @Button_1Pushed, true);
            app.Button_1.Text = '1';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '2';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '3';
            app.SubstractButton = uibutton(app.UIFigure, 'push');
            app.SubstractButton.ButtonPushedFcn = createCallbackFcn(app, @SubstractButtonPushed, true);
            app.SubstractButton.Text = '*';
            app.DivideButton = uibutton(app.UIFigure, 'push');
            app.DivideButton.ButtonPushedFcn = createCallbackFcn(app, @DivideButtonPushed, true);
            app.DivideButton.Text = '/';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'C';
            app.MinusButton = uibutton(app.UIFigure, 'push');
            app.MinusButton.ButtonPushedFcn = createCallbackFcn(app, @MinusButtonPushed, true);
            app.MinusButton.Text = '-';
            app.PlusButton = uibutton(app.UIFigure, 'push');
            app.PlusButton.ButtonPushedFcn = createCallbackFcn(app, @PlusButtonPushed, true);
            app.PlusButton.Text = '+';
            app.LogButton = uibutton(app.UIFigure, 'push');
            app.LogButton.ButtonPushedFcn = createCallbackFcn(app, @LogButtonPushed, true);
            app.LogButton.Text = 'log';
            app.Button_0 = uibutton(app.UIFigure, 'push');
            app.Button_0.ButtonPushedFcn = createCallbackFcn(app, @Button_0Pushed, true);
            app.Button_0.Text = '0';
            app.DotButton = uibutton(app.UIFigure, 'push');
            app.DotButton.ButtonPushedFcn = createCallbackFcn(app, @DotButtonPushed, true);
            app.DotButton.Text = '.';
            app.EqualityButton = uibutton(app.UIFigure, 'push');
            app.EqualityButton.ButtonPushedFcn = createCallbackFcn(app, @EqualityButtonPushed, true);
            app.EqualityButton.Text = '=';
            app.DeleteButton = uibutton(app.UIFigure, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = '🡰';
            app.LeftBracketButton = uibutton(app.UIFigure, 'push');
            app.LeftBracketButton.ButtonPushedFcn = createCallbackFcn(app, @LeftBracketButtonPushed, true);
            app.LeftBracketButton.Text = '(';
            app.RightBracketButton = uibutton(app.UIFigure, 'push');
            app.RightBracketButton.ButtonPushedFcn = createCallbackFcn(app, @RightBracketButtonPushed, true);
            app.RightBracketButton.Text = ')';
            app.SquareButton = uibutton(app.UIFigure, 'push');
            app.SquareButton.ButtonPushedFcn = createCallbackFcn(app, @SquareButtonPushed, true);
            app.SquareButton.Text = '√';
            app.Raising2Button = uibutton(app.UIFigure, 'push');
            app.Raising2Button.ButtonPushedFcn = createCallbackFcn(app, @Raising2ButtonPushed, true);
            app.Raising2Button.Text = '^';
            app.CountingTextArea = uitextarea(app.UIFigure);
            app.CountingTextArea.Editable = 'off';
            app.ResultTextArea = uitextarea(app.UIFigure);
            app.ResultTextArea.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = easy_calc

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end